classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RadioLampLabel                  matlab.ui.control.Label
        RadioLamp                       matlab.ui.control.Lamp
        AnswerLampLabel                 matlab.ui.control.Label
        AnswerLamp                      matlab.ui.control.Lamp
        DeclineLampLabel                matlab.ui.control.Label
        DeclineLamp                     matlab.ui.control.Lamp
        IncomingcallsLabel              matlab.ui.control.Label
        GPSLampLabel                    matlab.ui.control.Label
        GPSLamp                         matlab.ui.control.Lamp
        StartButton                     matlab.ui.control.Button
        NALabel                         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        numberoffingersdetectedLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0; % flag to allow infinite loop
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % open matlab code file
            app.flag = 1;
            while(app.flag) % infinite loop 
                % call function to count finger detacted at steering wheel
                [angle, distance, fingers] = countFingerNEW(); 
                app.NALabel.Text = num2str(fingers);
                % plot graph
                pax = polaraxes(app.Panel);
                polarplot(pax, deg2rad(angle), distance, 'o');
                %% polarplot(deg2rad(angle), distance, 'o');
%                 fig = uifigure('UserData', distance);
                %% fig.
                % polaraxe = polaraxes(app.UIAxes);
                % polarplot(deg2rad(angle), distance, 'o')
                % if 1 finger, pick up call (ans green)
                if (fingers == 1)
                    app.AnswerLamp.Color = 'green';
                    app.DeclineLamp.Color = 'black';
                    
                % if 2 fingers, end / hang up call (decline red, ans black)
                elseif (fingers == 2)
                    app.AnswerLamp.Color = 'black';
                    app.DeclineLamp.Color = 'red';
                    
                    % (after 1 second, decline turn black again)
                    pause(1);
                    app.DeclineLamp.Color = 'black';
                    
                % if 3 fingers, turn on radio (on - green, off - black)
                elseif (fingers == 3)
                    app.RadioLamp.Color = 'green';
                    
                % if 4 fingers, turn off radio (on - green, off - black)
                elseif (fingers == 4)
                    app.RadioLamp.Color = 'black';
                    
                % if 3 fingers, turn on radio (on - green, off - black)
                elseif (fingers == 5)
                    app.GPSLamp.Color = 'green';
                    
                % if 4 fingers, turn off radio (on - green, off - black)
                elseif (fingers == 6)
                    app.GPSLamp.Color = 'black';
                end
                
                pause(2); % pause 2 seconds to be able to see the response
            end
            % to do:
                % debug error when calling the function
                % how the person need to maintain the gesture b4 a respond
                % accuarcy
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %StartButtonPushed()
            app.flag = 0;
            app.AnswerLamp.Color = 'black';
            app.DeclineLamp.Color = 'black';
            app.RadioLamp.Color = 'black';
            app.GPSLamp.Color = 'black';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RadioLampLabel = uilabel(app.UIFigure);
            app.RadioLampLabel.Text = 'Radio';
            app.RadioLamp = uilamp(app.UIFigure);
            app.AnswerLampLabel = uilabel(app.UIFigure);
            app.AnswerLampLabel.Text = 'Answer';
            app.AnswerLamp = uilamp(app.UIFigure);
            app.DeclineLampLabel = uilabel(app.UIFigure);
            app.DeclineLampLabel.Text = 'Decline';
            app.DeclineLamp = uilamp(app.UIFigure);
            app.IncomingcallsLabel = uilabel(app.UIFigure);
            app.IncomingcallsLabel.Text = 'Incoming calls';
            app.GPSLampLabel = uilabel(app.UIFigure);
            app.GPSLampLabel.Text = 'GPS';
            app.GPSLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start !';
            app.NALabel = uilabel(app.UIFigure);
            app.NALabel.Text = 'N/A';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.numberoffingersdetectedLabel_2 = uilabel(app.UIFigure);
            app.numberoffingersdetectedLabel_2.Text = 'number of fingers detected:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end